classdef Finances_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Finances                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        ImportExpensesxlsxMenu   matlab.ui.container.Menu
        ClonePreviousMonthMenu   matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        BalanceSheetTab          matlab.ui.container.Tab
        IncomeandExpenseTab      matlab.ui.container.Tab
        ExpenseBreakdownTab      matlab.ui.container.Tab
        MonthPanel               matlab.ui.container.Panel
        YearDropDownLabel        matlab.ui.control.Label
        YearDropDown             matlab.ui.control.DropDown
        MonthSpinner             matlab.ui.control.Spinner
        NotesTextAreaLabel       matlab.ui.control.Label
        NotesTextArea            matlab.ui.control.TextArea
        SaveMonthButton          matlab.ui.control.Button
        MonthDropDownLabel       matlab.ui.control.Label
        MonthDropDown            matlab.ui.control.DropDown
        MonthInfoPanel           matlab.ui.container.Panel
        WellsFargoCheckingEditFieldLabel  matlab.ui.control.Label
        WellsFargoBox            matlab.ui.control.NumericEditField
        RobinhoodEditFieldLabel  matlab.ui.control.Label
        RobinhoodBox             matlab.ui.control.NumericEditField
        FinnCUEditFieldLabel     matlab.ui.control.Label
        FinnCUBox                matlab.ui.control.NumericEditField
        PayPalEditFieldLabel     matlab.ui.control.Label
        PayPalBox                matlab.ui.control.NumericEditField
        NSLSCEditFieldLabel      matlab.ui.control.Label
        NSLSCBox                 matlab.ui.control.NumericEditField
        WellsFargoSavingsLabel   matlab.ui.control.Label
        WellsFargoBox_2          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Year % Description
        Month % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MonthDropDown.ItemsData = 1:1:12;
            app.MonthDropDown.Value = 1;
            Data = [];
            Data.Y2020 = [];
            Data.Y2020.Balances = [];
            Data.Y2020.Deltas = [];            
            Data.Y2020.Expenses = [];  
            Data.Y2020.Notes = [];             
            Data.Y2020.Complete = [];     
            
            app.Year = 2020;
            app.Month = 1;
            
        end

        % Value changed function: MonthSpinner
        function MonthSpinnerValueChanged(app, event)
            app.MonthDropDown.Value = -app.MonthSpinner.Value;  
            app.Month = -app.MonthSpinner.Value;             
        end

        % Value changed function: MonthDropDown
        function MonthDropDownValueChanged(app, event)
            app.MonthSpinner.Value = -app.MonthDropDown.Value; 
            app.Month = app.MonthDropDown.Value;             
        end

        % Button pushed function: SaveMonthButton
        function SaveMonthButtonPushed(app, event)
            YearName = strcat('Y',num2str(app.Year));
            
            Data(app.Month).(char(YearName)).Balances.WF = app.WellsFargoBox.Value - app.WellsFargoBox_2.Value;
            Data(app.Month).(char(YearName)).Balances.WF_Savings = app.WellsFargoBox_2.Value;              
            Data(app.Month).(char(YearName)).Balances.RH = app.RobinhoodBox.Value;            
            Data(app.Month).(char(YearName)).Balances.FinnCU = app.FinnCUBox.Value;              
            Data(app.Month).(char(YearName)).Balances.PayPal = app.PayPalBox.Value;                        
            Data(app.Month).(char(YearName)).Balances.NSLSC = app.NSLSCBox.Value;             
            
            if app.Month == 1 %Find year and month of previous month for delta calcs
            PrevYear = strcat('Y',num2str(app.Year-1)); 
            PrevMonth = app.Month;
            else
                PrevYear = app.Year;
                PrevMonth = app.Month - 1;
            end
            
            if app.Year == 2020 && app.Month < 6 %Start of entries, cant find deltas until june (2nd month)
            Data(app.Month).(char(YearName)).Deltas.WF = 0;
            Data(app.Month).(char(YearName)).Deltas.WF_Savings = 0;              
            Data(app.Month).(char(YearName)).Deltas.RH = 0;            
            Data(app.Month).(char(YearName)).Deltas.FinnCU = 0;              
            Data(app.Month).(char(YearName)).Deltas.PayPal = 0;                        
            Data(app.Month).(char(YearName)).Deltas.NSLSC = 0;                
            else
            Data(app.Month).(char(YearName)).Deltas.WF = Data(app.Month).(char(YearName)).Balances.WF - Data(PrevMonth).(char(PrevYear)).Balances.WF;
            Data(app.Month).(char(YearName)).Deltas.WF_Savings = Data(app.Month).(char(YearName)).Balances.WF_Savings - Data(PrevMonth).(char(PrevYear)).Balances.WF_Savings;              
            Data(app.Month).(char(YearName)).Deltas.RH = Data(app.Month).(char(YearName)).Balances.RH - Data(PrevMonth).(char(PrevYear)).Balances.RH;            
            Data(app.Month).(char(YearName)).Deltas.FinnCU = Data(app.Month).(char(YearName)).Balances.FinnCU - Data(PrevMonth).(char(PrevYear)).Balances.FinnCU;              
            Data(app.Month).(char(YearName)).Deltas.PayPal = Data(app.Month).(char(YearName)).Balances.PayPal - Data(PrevMonth).(char(PrevYear)).Balances.PayPal;                      
            Data(app.Month).(char(YearName)).Deltas.NSLSC = Data(app.Month).(char(YearName)).Balances.NSLSC - Data(PrevMonth).(char(PrevYear)).Balances.NSLSC;              
            end
            
            Data(app.Month).(char(YearName)).Notes = app.NotesTextArea.Value;            
            Data(app.Month).(char(YearName)).Complete = 1; 
            
            uialert(app.Finances,'Month Saved', 'MonthSaved')
            
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            app.Year = cell2mat(app.YearDropDown.Value);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Finances = uifigure('Visible', 'off');
            app.Finances.Name = 'Orion Finances';
            app.FileMenu = uimenu(app.Finances);
            app.FileMenu.Text = ' File ';
            app.ImportExpensesxlsxMenu = uimenu(app.FileMenu);
            app.ImportExpensesxlsxMenu.Text = 'Import Expenses (.xlsx)';
            app.ClonePreviousMonthMenu = uimenu(app.FileMenu);
            app.ClonePreviousMonthMenu.Separator = 'on';
            app.ClonePreviousMonthMenu.Text = 'Clone Previous Month';
            app.TabGroup = uitabgroup(app.Finances);
            app.BalanceSheetTab = uitab(app.TabGroup);
            app.BalanceSheetTab.Title = 'Balance Sheet';
            app.IncomeandExpenseTab = uitab(app.TabGroup);
            app.IncomeandExpenseTab.Title = 'Income and Expense';
            app.ExpenseBreakdownTab = uitab(app.TabGroup);
            app.ExpenseBreakdownTab.Title = 'Expense Breakdown';
            app.MonthPanel = uipanel(app.Finances);
            app.MonthPanel.Title = 'Month';
            app.YearDropDownLabel = uilabel(app.MonthPanel);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.MonthPanel);
            app.YearDropDown.Items = {'2020', '2021'};
            app.YearDropDown.Editable = 'on';
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = '2020';
            app.MonthSpinner = uispinner(app.MonthPanel);
            app.MonthSpinner.Limits = [-12 -1];
            app.MonthSpinner.RoundFractionalValues = 'on';
            app.MonthSpinner.ValueChangedFcn = createCallbackFcn(app, @MonthSpinnerValueChanged, true);
            app.MonthSpinner.Value = -1;
            app.NotesTextAreaLabel = uilabel(app.MonthPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.MonthPanel);
            app.SaveMonthButton = uibutton(app.MonthPanel, 'push');
            app.SaveMonthButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMonthButtonPushed, true);
            app.SaveMonthButton.Text = 'Save Month';
            app.MonthDropDownLabel = uilabel(app.MonthPanel);
            app.MonthDropDownLabel.Text = 'Month';
            app.MonthDropDown = uidropdown(app.MonthPanel);
            app.MonthDropDown.Items = {'JAN', 'FEB', 'MAR', 'APR', 'MAY', 'JUN', 'JUL', 'AUG', 'SEP', 'OCT', 'NOV', 'DEC'};
            app.MonthDropDown.Editable = 'on';
            app.MonthDropDown.ValueChangedFcn = createCallbackFcn(app, @MonthDropDownValueChanged, true);
            app.MonthDropDown.Value = 'JAN';
            app.MonthInfoPanel = uipanel(app.Finances);
            app.MonthInfoPanel.Title = 'Month Info';
            app.WellsFargoCheckingEditFieldLabel = uilabel(app.MonthInfoPanel);
            app.WellsFargoCheckingEditFieldLabel.Text = 'Wells Fargo Checking';
            app.WellsFargoBox = uieditfield(app.MonthInfoPanel, 'numeric');
            app.RobinhoodEditFieldLabel = uilabel(app.MonthInfoPanel);
            app.RobinhoodEditFieldLabel.Text = 'Robinhood';
            app.RobinhoodBox = uieditfield(app.MonthInfoPanel, 'numeric');
            app.FinnCUEditFieldLabel = uilabel(app.MonthInfoPanel);
            app.FinnCUEditFieldLabel.Text = 'FinnCU';
            app.FinnCUBox = uieditfield(app.MonthInfoPanel, 'numeric');
            app.PayPalEditFieldLabel = uilabel(app.MonthInfoPanel);
            app.PayPalEditFieldLabel.Text = 'PayPal';
            app.PayPalBox = uieditfield(app.MonthInfoPanel, 'numeric');
            app.NSLSCEditFieldLabel = uilabel(app.MonthInfoPanel);
            app.NSLSCEditFieldLabel.Text = 'NSLSC';
            app.NSLSCBox = uieditfield(app.MonthInfoPanel, 'numeric');
            app.WellsFargoSavingsLabel = uilabel(app.MonthInfoPanel);
            app.WellsFargoSavingsLabel.Text = 'Wells Fargo Savings';
            app.WellsFargoBox_2 = uieditfield(app.MonthInfoPanel, 'numeric');
            % Show the figure after all components are created
            app.Finances.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Finances_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Finances)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Finances)
        end
    end
end